classdef LinearRegressionAPP < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        ConstTitleLabel             matlab.ui.control.Label
        plotFigureGroup             matlab.ui.container.TabGroup
        regressionEffectFigureTab   matlab.ui.container.Tab
        regressionEffectAxes        matlab.ui.control.UIAxes
        plotRegressionEffectButton  matlab.ui.control.Button
        residualsFigureTab          matlab.ui.container.Tab
        chooseType                  matlab.ui.container.ButtonGroup
        fitted                      matlab.ui.control.RadioButton
        histogram                   matlab.ui.control.RadioButton
        probability                 matlab.ui.control.RadioButton
        pearson                     matlab.ui.control.RadioButton
        lagged                      matlab.ui.control.RadioButton
        symmetry                    matlab.ui.control.RadioButton
        caseorder                   matlab.ui.control.RadioButton
        plotResiduals               matlab.ui.control.Button
        residualsAxes               matlab.ui.control.UIAxes
        plotGroup                   matlab.ui.control.Button
        alertLabel                  matlab.ui.control.Label
        XYTab                       matlab.ui.container.Tab
        XYAxes                      matlab.ui.control.UIAxes
        Label                       matlab.ui.control.Label
        inputXY                     matlab.ui.control.NumericEditField
        XYAlertLabel                matlab.ui.control.Label
        plotXY                      matlab.ui.control.Button
        plotXY_all                  matlab.ui.control.Button
        XYInputLabel                matlab.ui.control.Label
        tableDataGroup              matlab.ui.container.TabGroup
        coeffData                   matlab.ui.container.Tab
        coeffDataTable              matlab.ui.control.Table
        residualsData               matlab.ui.container.Tab
        residualsDataTable          matlab.ui.control.Table
        EditFieldLabel              matlab.ui.control.Label
        saveDataRoute               matlab.ui.control.EditField
        saveDataButton              matlab.ui.control.Button
        Label_2                     matlab.ui.control.Label
        setAlpha                    matlab.ui.control.NumericEditField
        showAlpha                   matlab.ui.control.Label
        alphaAlert                  matlab.ui.control.Label
        analyze_R2                  matlab.ui.control.Label
        analyze_F                   matlab.ui.control.Label
        analye_P                    matlab.ui.control.Label
        analyze_sigma               matlab.ui.control.Label
        screenDataButton            matlab.ui.control.Button
        screenDataLabel             matlab.ui.control.Label
        screenAlert                 matlab.ui.control.Label
    end

    properties (SetAccess = public)
        linearRegression % 模型
        residualsType % 选择残差图名称
        XNumber       %自变量个数
    end
    
    methods (Access = private)
        
        function EnglishString = translate(~,ChinesString)
            str = validatestring(ChinesString,{'残差与滞后残差图','残差对称图','残差序列图', ...
                '残差与拟合值图','残差分布直方图','残差正态分布图','标准化残差图'});
            switch str
                case '残差与滞后残差图'
                    EnglishString = 'lagged';
                case '残差对称图'
                    EnglishString = 'symmetry';
                case '残差序列图'
                    EnglishString = 'caseorder';
                case '残差与拟合值图'
                    EnglishString = 'fitted';
                case '残差分布直方图'
                    EnglishString = 'histogram';
                case '残差正态分布图'
                    EnglishString = 'probability';
                case '标准化残差图'
                    EnglishString = 'pearson';
            end
        end
        
        function upDataUI(app)
            
            % 残差作图区
            app.chooseTypeSelectionChanged();
            
            % 自变量作图区
            app.inputXYValueChanged();
            
            % 拟合效果区
            h = app.linearRegression.plotRegressionEffect(app.regressionEffectAxes);
            h{end}.setFontSize(8);
            
            % 表区域
            app.coeffDataTable.Data = app.linearRegression.getCoeff();
            coeffDataCell = app.linearRegression.getCoeff('cell');
            app.coeffDataTable.ColumnName = coeffDataCell(1,2:end);
            app.coeffDataTable.RowName = coeffDataCell(2:end,1);
            
            app.residualsDataTable.Data = app.linearRegression.getResiduals();
            residualsDataCell = app.linearRegression.getResiduals('cell');
            app.residualsDataTable.ColumnName = residualsDataCell(1,:);
            
            % analyze 区域
            app.analyze_R2.Text = ['可决系数 R² 的值为 ', ...
                num2str(app.linearRegression.stats(1)),' 越接近1表示效果越好'];
            
            m = size(app.linearRegression.forecastData.X,2);
            n = size(app.linearRegression.forecastData.X,1);
            F = finv(1-app.linearRegression.alpha,m,n-m-1);
            app.analyze_F.Text = ['F 统计量观测值为 ', ...
                num2str(app.linearRegression.stats(2)),' 应大于 ',num2str(F)];
            
            app.analye_P.Text = ['检验 P 值为 ', ...
                num2str(app.linearRegression.stats(3)),' 应小于 α 即 ', ...
                num2str(app.linearRegression.alpha)];
            
            app.analyze_sigma.Text = ['方差 σ² 的无偏估计为 ', ...
                num2str(app.linearRegression.stats(4)),' 越小越好'];
            
            % screen 区域
            if isempty(app.linearRegression.problemDataIndex)
                app.screenAlert.Visible = 'on';
                app.screenDataButton.Enable = 'off';
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, X, Y)
            if nargin == 3
                app.linearRegression = LinearRegression(X,Y);
            elseif nargin == 2
               if isa(X,'LinearRegression')
                   app.linearRegression = X;
               else
                   error('只输入一个参数必须输入 LinearRegression 类');
               end
            end
            app.XNumber = size(app.linearRegression.forecastData.X,2);
            app.XYInputLabel.Text = ['输入范围 0 到 ',num2str(app.XNumber),' 整数'];
            app.upDataUI();
        end

        % Selection changed function: chooseType
        function chooseTypeSelectionChanged(app, event)
            selectedButton = app.chooseType.SelectedObject;
            string = app.translate(selectedButton.Text);
            app.residualsType = string;
            switch string
                case {'lagged','symmetry'}
                    cla(app.residualsAxes,'reset');
                    app.alertLabel.Visible = 'on';
                otherwise
                    app.alertLabel.Visible = 'off';
                    cla(app.residualsAxes,'reset');
                    h = app.linearRegression.plotResiduals(string,app.residualsAxes);
                    h{end}.setFontSize(8);
            end
        end

        % Button pushed function: plotResiduals
        function plotResidualsButtonPushed(app, event)
            figure()
            app.linearRegression.plotResiduals(app.residualsType);
        end

        % Button pushed function: plotGroup
        function plotGroupButtonPushed(app, event)
            figure()
            app.linearRegression.plotResiduals();
        end

        % Button pushed function: plotRegressionEffectButton
        function plotRegressionEffectButtonPushed(app, event)
            figure()
            app.linearRegression.plotRegressionEffect();
        end

        % Value changed function: inputXY
        function inputXYValueChanged(app, event)
            value = app.inputXY.Value;
            try
                validateattributes(value,{'numeric'}, ...
                    {'size',[1 1],'integer','>',0,'<=',app.XNumber}, ...
                    'setIndex','index');
                cla(app.XYAxes,'reset');
                app.XYAlertLabel.Visible = 'off';
                h = app.linearRegression.plotXY(value,app.XYAxes);
                h{end}.setFontSize(8);
            catch 
                app.XYAlertLabel.Visible = 'on';
            end
        end

        % Button pushed function: plotXY
        function plotXYButtonPushed(app, event)
            value = app.inputXY.Value;
            try
                validateattributes(value,{'numeric'}, ...
                    {'size',[1 1],'integer','>',0,'<=',app.XNumber}, ...
                    'setIndex','index');
                figure();
                app.linearRegression.plotXY(value);
            catch 
            end
        end

        % Button pushed function: plotXY_all
        function plotXY_allButtonPushed(app, event)
            figure();
            app.linearRegression.plotXY();
        end

        % Button pushed function: saveDataButton
        function saveDataButtonPushed(app, event)
            value = app.saveDataRoute.Value;
            try
                app.linearRegression.writeResult(value);
            catch message
                disp(message)
            end
        end

        % Value changed function: setAlpha
        function setAlphaValueChanged(app, event)
            value = app.setAlpha.Value;
            try
                app.linearRegression.alpha = value;
                app.alphaAlert.Visible = 'off';
                app.showAlpha.Text = ['置信概率：',num2str(100*(1-value)),'%'];
                app.upDataUI();
            catch 
                app.alphaAlert.Visible = 'on';
            end
        end

        % Button pushed function: screenDataButton
        function screenDataButtonPushed(app, event)
            NewObj = app.linearRegression.screenData();
            LinearRegressionAPP(NewObj);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.ConstTitleLabel = uilabel(app.UIFigure);
            app.ConstTitleLabel.Text = 'LinearRegression工具箱';
            app.plotFigureGroup = uitabgroup(app.UIFigure);
            app.regressionEffectFigureTab = uitab(app.plotFigureGroup);
            app.regressionEffectFigureTab.Title = '回归效果图';
            app.regressionEffectAxes = uiaxes(app.regressionEffectFigureTab);
            title(app.regressionEffectAxes, 'Title')
            xlabel(app.regressionEffectAxes, 'X')
            ylabel(app.regressionEffectAxes, 'Y')
            app.plotRegressionEffectButton = uibutton(app.regressionEffectFigureTab, 'push');
            app.plotRegressionEffectButton.ButtonPushedFcn = createCallbackFcn(app, @plotRegressionEffectButtonPushed, true);
            app.plotRegressionEffectButton.Text = '单独作图';
            app.residualsFigureTab = uitab(app.plotFigureGroup);
            app.residualsFigureTab.Title = '残差分析图';
            app.chooseType = uibuttongroup(app.residualsFigureTab);
            app.chooseType.SelectionChangedFcn = createCallbackFcn(app, @chooseTypeSelectionChanged, true);
            app.chooseType.Title = '请选择类型';
            app.fitted = uiradiobutton(app.chooseType);
            app.fitted.Text = '残差与拟合值图';
            app.fitted.Value = true;
            app.histogram = uiradiobutton(app.chooseType);
            app.histogram.Text = '残差分布直方图';
            app.probability = uiradiobutton(app.chooseType);
            app.probability.Text = '残差正态分布图';
            app.pearson = uiradiobutton(app.chooseType);
            app.pearson.Text = '标准化残差图';
            app.lagged = uiradiobutton(app.chooseType);
            app.lagged.Text = '残差与滞后残差图';
            app.symmetry = uiradiobutton(app.chooseType);
            app.symmetry.Text = '残差对称图';
            app.caseorder = uiradiobutton(app.chooseType);
            app.caseorder.Text = '残差序列图';
            app.plotResiduals = uibutton(app.residualsFigureTab, 'push');
            app.plotResiduals.ButtonPushedFcn = createCallbackFcn(app, @plotResidualsButtonPushed, true);
            app.plotResiduals.Text = '单独作图';
            app.residualsAxes = uiaxes(app.residualsFigureTab);
            title(app.residualsAxes, 'Title')
            xlabel(app.residualsAxes, 'X')
            ylabel(app.residualsAxes, 'Y')
            app.plotGroup = uibutton(app.residualsFigureTab, 'push');
            app.plotGroup.ButtonPushedFcn = createCallbackFcn(app, @plotGroupButtonPushed, true);
            app.plotGroup.Text = '经典组合图';
            app.alertLabel = uilabel(app.residualsFigureTab);
            app.alertLabel.Visible = 'off';
            app.alertLabel.Text = '此类型残差分析图不支持预览';
            app.XYTab = uitab(app.plotFigureGroup);
            app.XYTab.Title = '自变量与预测值';
            app.XYAxes = uiaxes(app.XYTab);
            title(app.XYAxes, 'Title')
            xlabel(app.XYAxes, 'X')
            ylabel(app.XYAxes, 'Y')
            app.Label = uilabel(app.XYTab);
            app.Label.Text = '请输入自变量序号';
            app.inputXY = uieditfield(app.XYTab, 'numeric');
            app.inputXY.ValueChangedFcn = createCallbackFcn(app, @inputXYValueChanged, true);
            app.inputXY.Value = 1;
            app.XYAlertLabel = uilabel(app.XYTab);
            app.XYAlertLabel.Visible = 'off';
            app.XYAlertLabel.Text = '输入序号不合法';
            app.plotXY = uibutton(app.XYTab, 'push');
            app.plotXY.ButtonPushedFcn = createCallbackFcn(app, @plotXYButtonPushed, true);
            app.plotXY.Text = '单独作图';
            app.plotXY_all = uibutton(app.XYTab, 'push');
            app.plotXY_all.ButtonPushedFcn = createCallbackFcn(app, @plotXY_allButtonPushed, true);
            app.plotXY_all.Text = '总图';
            app.XYInputLabel = uilabel(app.XYTab);
            app.XYInputLabel.Text = 'InputLabel';
            app.tableDataGroup = uitabgroup(app.UIFigure);
            app.coeffData = uitab(app.tableDataGroup);
            app.coeffData.Title = '回归系数数据';
            app.coeffDataTable = uitable(app.coeffData);
            app.coeffDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.coeffDataTable.RowName = {};
            app.residualsData = uitab(app.tableDataGroup);
            app.residualsData.Title = '残差数据';
            app.residualsDataTable = uitable(app.residualsData);
            app.residualsDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.residualsDataTable.RowName = {};
            app.EditFieldLabel = uilabel(app.UIFigure);
            app.EditFieldLabel.Text = '保存数据文件名';
            app.saveDataRoute = uieditfield(app.UIFigure, 'text');
            app.saveDataRoute.Value = 'LinearRegressionData';
            app.saveDataButton = uibutton(app.UIFigure, 'push');
            app.saveDataButton.ButtonPushedFcn = createCallbackFcn(app, @saveDataButtonPushed, true);
            app.saveDataButton.Text = '保存为 Excel 文件在当前路径';
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'α(0~1)';
            app.setAlpha = uieditfield(app.UIFigure, 'numeric');
            app.setAlpha.ValueChangedFcn = createCallbackFcn(app, @setAlphaValueChanged, true);
            app.setAlpha.Value = 0.05;
            app.showAlpha = uilabel(app.UIFigure);
            app.showAlpha.Text = '置信概率：95%';
            app.alphaAlert = uilabel(app.UIFigure);
            app.alphaAlert.Visible = 'off';
            app.alphaAlert.Text = '输入数据不合法';
            app.analyze_R2 = uilabel(app.UIFigure);
            app.analyze_R2.Text = '可决系数 R² 的值为 0.913095 越接近1表示效果越好';
            app.analyze_F = uilabel(app.UIFigure);
            app.analyze_F.Text = 'F 统计量观测值为 70.045358 应大于 3.098391 ';
            app.analye_P = uilabel(app.UIFigure);
            app.analye_P.Text = '检验 P 值为 0.000000 应小于 α 即 0.050000';
            app.analyze_sigma = uilabel(app.UIFigure);
            app.analyze_sigma.Text = '方差 σ² 的无偏估计为 2.986782 越小越好';
            app.screenDataButton = uibutton(app.UIFigure, 'push');
            app.screenDataButton.ButtonPushedFcn = createCallbackFcn(app, @screenDataButtonPushed, true);
            app.screenDataButton.Text = '筛选回归数据';
            app.screenDataLabel = uilabel(app.UIFigure);
            app.screenDataLabel.Text = '剔除残差在 μ ± 3×σ 之外的数据，重新进行回归分析';
            app.screenAlert = uilabel(app.UIFigure);
            app.screenAlert.Visible = 'off';
            app.screenAlert.Text = '数据已满足条件，无法筛选';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LinearRegressionAPP(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end